classdef GRAPHFINALLY < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        UIAxes                 matlab.ui.control.UIAxes          
        plotGRAPH              matlab.ui.control.Button           % Построи...
        LabelEditField         matlab.ui.control.Label            % Y(X) =
        yinput                 matlab.ui.control.EditField       
        Label                  matlab.ui.control.Label            % ПОСТРОЕ...
        grid                   matlab.ui.control.CheckBox         % Координ...
        points                 matlab.ui.control.CheckBox         % Выделит...
        LabelEditField2        matlab.ui.control.Label            % X(min) =
        x1                     matlab.ui.control.EditField       
        LabelNumericEditField  matlab.ui.control.Label            % n = 
        n                      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField3        matlab.ui.control.Label            % X(max) =
        x2                     matlab.ui.control.EditField       
        LabelNumericEditField2 matlab.ui.control.Label            % Ось X (...
        oxmin                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % Ось X (...
        oxmax                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % Ось Y (...
        oymin                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % Ось Y (...
        oymax                  matlab.ui.control.NumericEditField % [-Inf Inf]
        xylim                  matlab.ui.control.Button           % Задать ...
        color                  matlab.ui.container.ButtonGroup    % Выберит...
        color1                 matlab.ui.control.RadioButton      % Синий
        color2                 matlab.ui.control.RadioButton      % Чёрный
        color3                 matlab.ui.control.RadioButton      % Красный
        save                   matlab.ui.control.Button           % Сохрани...
        Button                 matlab.ui.control.Button           % Построи...
        LabelTextArea          matlab.ui.control.Label           
        TextArea               matlab.ui.control.TextArea         % Для пос...
        Label2                 matlab.ui.control.Label            % Инструк...
        Button2                matlab.ui.control.Button           % Очистит...
    end


    properties (Access = private)
            x;
            y;
            valuey;
            valueoxmin;
            valueoxmax;
            valueoymin;
            valueoymax;
            valuex1;
            valuex2;
            valuen;
            x22;
            x11;
            nn;
            newXLimits;
            newYLimits;
            valuegrid;
            valuepoints;
            selectedColor;
            a;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.selectedColor = 'blue';
        end

        % grid value changed function
        function gridValueChanged(app)
            app.valuegrid = app.grid.Value;
            if app.valuegrid == 1
                grid(app.UIAxes, 'on')
            else
                grid(app.UIAxes, 'off')
            end
        end

        % yinput value changed function
        function yinputValueChanged(app)
            app.valuey = app.yinput.Value;          
        end

        % points value changed function
        function pointsValueChanged(app)
            app.valuepoints = app.points.Value;
            app.valuepoints = app.points.Value;
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            
            
            if app.valuepoints == 1
                
                line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.', 'Color', app.selectedColor);
            else
                
                line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);
                cla(app.UIAxes);
                line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);
            end

            
        end

        % x1 value changed function
        function x1ValueChanged(app)
            app.valuex1 = app.x1.Value;
            
        end

        % x2 value changed function
        function x2ValueChanged(app)
            app.valuex2 = app.x2.Value;
            
        end

        % n value changed function
        function nValueChanged(app)
            app.valuen = app.n.Value;
            
        end

        % plotGRAPH button pushed function
        function plotGRAPHButtonPushed(app)
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            app.y = eval(app.valuey);
            if app.valuegrid == 1 
               if app.valuepoints == 1
                   line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.', 'Color', app.selectedColor);grid(app.UIAxes, 'on');
               else
                   line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'on');
                   cla(app.UIAxes);
                   line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'on');
               end
            else
               if app.valuepoints == 0
                   line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'off');
                   cla(app.UIAxes);
                   line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'off');
                   
               else
                   line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'off');
               end
            end 


        end

        % oxmin value changed function
        function oxminValueChanged(app)
            app.valueoxmin = app.oxmin.Value;
            
        end

        % oxmax value changed function
        function oxmaxValueChanged(app)
            app.valueoxmax = app.oxmax.Value;
            
        end

        % oymin value changed function
        function oyminValueChanged(app)
            app.valueoymin = app.oymin.Value;
            
        end

        % oymax value changed function
        function oymaxValueChanged(app)
            app.valueoymax = app.oymax.Value;
            
        end

        % xylim button pushed function
        function xylimButtonPushed(app)

            app.newXLimits = [app.valueoxmin, app.valueoxmax];
            app.newYLimits = [app.valueoymin,app.valueoymax];
            app.UIAxes.XLim = app.newXLimits;
            app.UIAxes.YLim = app.newYLimits;
        end

        % color selection change function
        function colorSelectionChanged(app, event)
            
            app.selectedColor = '';
            selectedButton = app.color.SelectedObject;
            app.valuepoints = app.points.Value;
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            
            if selectedButton == app.color1 
                app.selectedColor = 'blue';
            elseif selectedButton == app.color2
                app.selectedColor = 'black';
            elseif selectedButton == app.color3
                app.selectedColor = 'red';
            end
            
            if ~isempty(app.selectedColor)
                if app.valuepoints == 1
                    line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.', 'Color', app.selectedColor)
                else
                    line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor)
                end
            end


        end

        % Button button pushed function
        function ButtonButtonPushed(app)
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            app.y = eval(app.valuey);
            if app.valuegrid == 1 
               if app.valuepoints == 1
                   plot(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.', 'Color', app.selectedColor);grid(app.UIAxes, 'on');
               else
                   plot(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'on');
                   cla(app.UIAxes);
                   plot(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'on');
               end
            else
               if app.valuepoints == 0
                   plot(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'off');
                   cla(app.UIAxes);
                   plot(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'off');
                   
               else
                   plot(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor);grid(app.UIAxes, 'off');
               end
            end 
        end

        % save button pushed function
        function saveButtonPushed(app)
            app.x11 = eval(app.valuex1);nValueChanged(app);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            app.y = eval(app.valuey);
            plot(x, app.y)
            xlabel('X');ylabel('Y');title(sprintf('График функции %s', app.valuey));xlim([app.valueoxmin, app.valueoxmax]);
            ylim([app.valueoymin,app.valueoymax]);
            [filename, pathname] = uiputfile('*.png', 'Сохранить график');
            frame = getframe(gcf);
            imageData = frame.cdata;
            imwrite(imageData, fullfile(pathname, filename));
        end

        % Button2 button pushed function
        function Button2ButtonPushed(app)
             cla(app.UIAxes);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.plotGRAPH = uibutton(app.UIFigure, 'push');
            app.plotGRAPH.ButtonPushedFcn = createCallbackFcn(app, @plotGRAPHButtonPushed);
            app.plotGRAPH.Text = 'Построить несколько графиков';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'Y(X) =';
            app.yinput = uieditfield(app.UIFigure, 'text');
            app.yinput.ValueChangedFcn = createCallbackFcn(app, @yinputValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ПОСТРОЕНИЕ ГРАФИКОВ';
            app.grid = uicheckbox(app.UIFigure);
            app.grid.ValueChangedFcn = createCallbackFcn(app, @gridValueChanged);
            app.grid.Text = 'Координатная сетка';
            app.points = uicheckbox(app.UIFigure);
            app.points.ValueChangedFcn = createCallbackFcn(app, @pointsValueChanged);
            app.points.Text = 'Выделить точки';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'X(min) =';
            app.x1 = uieditfield(app.UIFigure, 'text');
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1ValueChanged);
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'n = ';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.n.ValueChangedFcn = createCallbackFcn(app, @nValueChanged);
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'X(max) =';
            app.x2 = uieditfield(app.UIFigure, 'text');
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2ValueChanged);
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'Ось X (min) = ';
            app.oxmin = uieditfield(app.UIFigure, 'numeric');
            app.oxmin.ValueChangedFcn = createCallbackFcn(app, @oxminValueChanged);
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'Ось X (max) = ';
            app.oxmax = uieditfield(app.UIFigure, 'numeric');
            app.oxmax.ValueChangedFcn = createCallbackFcn(app, @oxmaxValueChanged);
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'Ось Y (min) = ';
            app.oymin = uieditfield(app.UIFigure, 'numeric');
            app.oymin.ValueChangedFcn = createCallbackFcn(app, @oyminValueChanged);
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'Ось Y (max) = ';
            app.oymax = uieditfield(app.UIFigure, 'numeric');
            app.oymax.ValueChangedFcn = createCallbackFcn(app, @oymaxValueChanged);
            app.xylim = uibutton(app.UIFigure, 'push');
            app.xylim.ButtonPushedFcn = createCallbackFcn(app, @xylimButtonPushed);
            app.xylim.Text = 'Задать масштаб';
            app.color = uibuttongroup(app.UIFigure);
            app.color.SelectionChangedFcn = createCallbackFcn(app, @colorSelectionChanged, true);
            app.color.Title = 'Выберите цвет для графика:';
            app.color.Units = 'pixels';
            app.color1 = uiradiobutton(app.color);
            app.color1.Text = 'Синий';
            app.color1.Value = true;
            app.color2 = uiradiobutton(app.color);
            app.color2.Text = 'Чёрный';
            app.color3 = uiradiobutton(app.color);
            app.color3.Text = 'Красный';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed);
            app.save.Text = 'Сохранить график';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonButtonPushed);
            app.Button.Text = 'Построить один график';
            app.LabelTextArea = uilabel(app.UIFigure);
            app.LabelTextArea.Text = '';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Для построения графика(-ов) введите значения f(x) в поле ввода Y(X), укажите промежуток значений X и кол-во точек графика.'; ''; 'Вы можете оформить график используя элементы интерфейса: Координатная сетка, Выделить точки, Выберите цвет для графика.'; ''; 'Масштаб по осям задаётся с помощью значений в полях ввода Ось X (min) и т.д. '; ''; 'Для построения графика(-ов) выберите нужну. функцию: построить один график или несколько.'; ''; 'Для сохранения в выбранном формате используется функция "Сохранить график"'};
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Инструкция:';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2ButtonPushed);
            app.Button2.Text = 'Очистить график';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GRAPHFINALLY()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end