classdef graphapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure            % UI Figure
        UIAxes          matlab.ui.control.UIAxes   
        Label           matlab.ui.control.Label     % Построение графиков
        grid            matlab.ui.control.CheckBox  % Сетка
        save            matlab.ui.control.Button    % Сохранить результат 
        points          matlab.ui.control.CheckBox  % Точки (*)
        plotPOINT       matlab.ui.control.Button    % Построить график
        LabelEditField  matlab.ui.control.Label     % x:
        inputx          matlab.ui.control.EditField
        LabelEditField2 matlab.ui.control.Label     % y:
        inputy          matlab.ui.control.EditField
    end

    properties (Access = private)
        x;
        y;        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                
            app.x = 1:5; app.y = (app.x).^3;
            plot(app.UIAxes,app.x,app.y)
        end

        % grid value changed function
        function gridValueChanged(app)
            value = app.grid.Value;
            if value == 1
                grid(app.UIAxes, 'on');
            else
                grid(app.UIAxes, 'off');
            end
            
        end

        % points value changed function
        function pointsValueChanged(app)
        value = app.points.Value;
        
        
        if value == 1
            plot(app.UIAxes,app.x, app.y, '-*')
            
        else
            plot(app.UIAxes,app.x, app.y, '-')  
        end

                
        end

        % inputx value changed function
        function inputxValueChanged(app)
             value1 = app.inputx.Value;
             app.x = str2double(value1);
        end

        % inputy value changed function
        function inputyValueChanged(app)
            value2 = app.inputy.Value;
            app.y = str2double(value2);
            
        end

        % plotPOINT button pushed function
        function plotPOINTButtonPushed(app)
            plot(app.UIAxes,app.x, app.y, '-')
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Построение графиков';
            app.grid = uicheckbox(app.UIFigure);
            app.grid.ValueChangedFcn = createCallbackFcn(app, @gridValueChanged);
            app.grid.Text = 'Сетка';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.Text = 'Сохранить результат ';
            app.points = uicheckbox(app.UIFigure);
            app.points.ValueChangedFcn = createCallbackFcn(app, @pointsValueChanged);
            app.points.Text = 'Точки (*)';
            app.plotPOINT = uibutton(app.UIFigure, 'push');
            app.plotPOINT.ButtonPushedFcn = createCallbackFcn(app, @plotPOINTButtonPushed);
            app.plotPOINT.Text = 'Построить график';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'x:';
            app.inputx = uieditfield(app.UIFigure, 'text');
            app.inputx.ValueChangedFcn = createCallbackFcn(app, @inputxValueChanged);
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'y:';
            app.inputy = uieditfield(app.UIFigure, 'text');
            app.inputy.ValueChangedFcn = createCallbackFcn(app, @inputyValueChanged);
        end
    end

    methods (Access = public)

        % Construct app
        function app = graphapp()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end