classdef nosave < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure                   % UI Figure
        UIAxes                 matlab.ui.control.UIAxes           % Title
        plotGRAPH              matlab.ui.control.Button           % построи...
        LabelEditField         matlab.ui.control.Label            % y
        yinput                 matlab.ui.control.EditField       
        Label                  matlab.ui.control.Label            % ПОСТРОЕ...
        grid                   matlab.ui.control.CheckBox         % грид
        points                 matlab.ui.control.CheckBox         % точки
        LabelEditField2        matlab.ui.control.Label            % x1
        x1                     matlab.ui.control.EditField       
        LabelNumericEditField  matlab.ui.control.Label            % n
        n                      matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField3        matlab.ui.control.Label            % x2
        x2                     matlab.ui.control.EditField       
        LabelNumericEditField2 matlab.ui.control.Label            % oxmin
        oxmin                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField3 matlab.ui.control.Label            % oxmax
        oxmax                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField4 matlab.ui.control.Label            % oymin
        oymin                  matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelNumericEditField5 matlab.ui.control.Label            % oxmax
        oymax                  matlab.ui.control.NumericEditField % [-Inf Inf]
        xylim                  matlab.ui.control.Button           % задать ...
        color                  matlab.ui.container.ButtonGroup    % цвет
        color1                 matlab.ui.control.RadioButton      % синий
        color2                 matlab.ui.control.RadioButton      % черный
        color3                 matlab.ui.control.RadioButton      % красный
        save                   matlab.ui.control.Button           % сохрани...
    end


    properties (Access = public)
            x;
            y;
            valuey;
            valueoxmin;
            valueoxmax;
            valueoymin;
            valueoymax;
            valuex1;
            valuex2;
            valuen;
            x22;
            x11;
            nn;
            newXLimits;
            newYLimits;
            valuegrid;
            valuepoints;
            selectedColor;

    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % grid value changed function
        function gridValueChanged(app)
            app.valuegrid = app.grid.Value;
            if app.valuegrid == 1
                grid(app.UIAxes, 'on')
            else
                grid(app.UIAxes, 'off')
            end
        end

        % yinput value changed function
        function yinputValueChanged(app)
            app.valuey = app.yinput.Value;          
        end

        % points value changed function
        function pointsValueChanged(app)
            app.valuepoints = app.points.Value;
            app.valuepoints = app.points.Value;
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            
            if app.valuepoints == 1
                line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.');
            else
                cla(app.UIAxes);
                line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-');
            end

            
        end

        % x1 value changed function
        function x1ValueChanged(app)
            app.valuex1 = app.x1.Value;
            
        end

        % x2 value changed function
        function x2ValueChanged(app)
            app.valuex2 = app.x2.Value;
            
        end

        % n value changed function
        function nValueChanged(app)
            app.valuen = app.n.Value;
            
        end

        % plotGRAPH button pushed function
        function plotGRAPHButtonPushed(app)
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            app.y = eval(app.valuey);
            app.valuegrid = 0;
            app.valuepoints = 0;
            plot(app.UIAxes, x, app.y);


        end

        % oxmin value changed function
        function oxminValueChanged(app)
            app.valueoxmin = app.oxmin.Value;
            
        end

        % oxmax value changed function
        function oxmaxValueChanged(app)
            app.valueoxmax = app.oxmax.Value;
            
        end

        % oymin value changed function
        function oyminValueChanged(app)
            app.valueoymin = app.oymin.Value;
            
        end

        % oymax value changed function
        function oymaxValueChanged(app)
            app.valueoymax = app.oymax.Value;
            
        end

        % xylim button pushed function
        function xylimButtonPushed(app)

            app.newXLimits = [app.valueoxmin, app.valueoxmax];
            app.newYLimits = [app.valueoymin,app.valueoymax];
            app.UIAxes.XLim = app.newXLimits;
            app.UIAxes.YLim = app.newYLimits;
        end

        % color selection change function
        function colorSelectionChanged(app, event)
            
            app.selectedColor = '';
            selectedButton = app.color.SelectedObject;
            app.valuepoints = app.points.Value;
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            
            if selectedButton == app.color1 
                app.selectedColor = 'blue';
            elseif selectedButton == app.color2
                app.selectedColor = 'black';
            elseif selectedButton == app.color3
                app.selectedColor = 'red';
            end
            
            if ~isempty(app.selectedColor)
                if app.valuepoints == 1
                    line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.', 'Color', app.selectedColor)
                else
                    line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Color', app.selectedColor)
                end
            end


        end

        % Switch value changed function
        function SwitchValueChanged(app)
            app.valuepoints = app.points.Value;
            app.valuepoints = app.points.Value;
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            switch app.Switch.Value;
                case 'On'
                    line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-', 'Marker', '.');
                case 'Off'
                    line(app.UIAxes, x, eval(app.valuey), 'LineStyle', '-');
            end
            
        end

        % save button pushed function
        function saveButtonPushed(app)
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            x = linspace(app.x11, app.x22, app.nn);
            app.y = eval(app.valuey);
            plot(x, app.y)
            xlabel('X');ylabel('Y');title('График функции');
            [filename, pathname] = uiputfile('*.png', 'Сохранить график');
             frame = getframe(gcf);
             imageData = frame.cdata;
             imwrite(imageData, fullfile(pathname, filename));


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.plotGRAPH = uibutton(app.UIFigure, 'push');
            app.plotGRAPH.ButtonPushedFcn = createCallbackFcn(app, @plotGRAPHButtonPushed);
            app.plotGRAPH.Text = 'построить график ';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'y';
            app.yinput = uieditfield(app.UIFigure, 'text');
            app.yinput.ValueChangedFcn = createCallbackFcn(app, @yinputValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ПОСТРОЕНИЕ ГРАФИКОВ';
            app.grid = uicheckbox(app.UIFigure);
            app.grid.ValueChangedFcn = createCallbackFcn(app, @gridValueChanged);
            app.grid.Text = 'грид';
            app.points = uicheckbox(app.UIFigure);
            app.points.ValueChangedFcn = createCallbackFcn(app, @pointsValueChanged);
            app.points.Text = 'точки';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'x1';
            app.x1 = uieditfield(app.UIFigure, 'text');
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1ValueChanged);
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'n';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.n.ValueChangedFcn = createCallbackFcn(app, @nValueChanged);
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'x2';
            app.x2 = uieditfield(app.UIFigure, 'text');
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2ValueChanged);
            app.LabelNumericEditField2 = uilabel(app.UIFigure);
            app.LabelNumericEditField2.Text = 'oxmin';
            app.oxmin = uieditfield(app.UIFigure, 'numeric');
            app.oxmin.ValueChangedFcn = createCallbackFcn(app, @oxminValueChanged);
            app.LabelNumericEditField3 = uilabel(app.UIFigure);
            app.LabelNumericEditField3.Text = 'oxmax';
            app.oxmax = uieditfield(app.UIFigure, 'numeric');
            app.oxmax.ValueChangedFcn = createCallbackFcn(app, @oxmaxValueChanged);
            app.LabelNumericEditField4 = uilabel(app.UIFigure);
            app.LabelNumericEditField4.Text = 'oymin';
            app.oymin = uieditfield(app.UIFigure, 'numeric');
            app.oymin.ValueChangedFcn = createCallbackFcn(app, @oyminValueChanged);
            app.LabelNumericEditField5 = uilabel(app.UIFigure);
            app.LabelNumericEditField5.Text = 'oxmax';
            app.oymax = uieditfield(app.UIFigure, 'numeric');
            app.oymax.ValueChangedFcn = createCallbackFcn(app, @oymaxValueChanged);
            app.xylim = uibutton(app.UIFigure, 'push');
            app.xylim.ButtonPushedFcn = createCallbackFcn(app, @xylimButtonPushed);
            app.xylim.Text = 'задать масштаб';
            app.color = uibuttongroup(app.UIFigure);
            app.color.SelectionChangedFcn = createCallbackFcn(app, @colorSelectionChanged, true);
            app.color.Title = 'цвет';
            app.color.Units = 'pixels';
            app.color1 = uiradiobutton(app.color);
            app.color1.Text = 'синий';
            app.color1.Value = true;
            app.color2 = uiradiobutton(app.color);
            app.color2.Text = 'черный';
            app.color3 = uiradiobutton(app.color);
            app.color3.Text = 'красный';
            app.save = uibutton(app.UIFigure, 'push');
            app.save.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed);
            app.save.Text = 'сохранить график';
        end
    end

    methods (Access = public)

        % Construct app
        function app = nosave()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end