classdef praktikagraph < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure                   % UI Figure
        UIAxes                matlab.ui.control.UIAxes           % Title
        plotGRAPH             matlab.ui.control.Button           % построит...
        LabelEditField        matlab.ui.control.Label            % y
        yinput                matlab.ui.control.EditField       
        Label                 matlab.ui.control.Label            % ПОСТРОЕН...
        grid                  matlab.ui.control.CheckBox         % грид
        points                matlab.ui.control.CheckBox         % точки
        LabelEditField2       matlab.ui.control.Label            % x1
        x1                    matlab.ui.control.EditField       
        LabelNumericEditField matlab.ui.control.Label            % n
        n                     matlab.ui.control.NumericEditField % [-Inf Inf]
        LabelEditField3       matlab.ui.control.Label            % x2
        x2                    matlab.ui.control.EditField       
    end


    properties (Access = public)
       valuey;
       valuex1;
       valuex2;
       valuen;
       x;
       x11;
       x22;
       nn;
       y;
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % grid value changed function
        function gridValueChanged(app)
            value = app.grid.Value;
            if value == 1
                grid(app.UIAxes, 'on')
            else
                grid(app.UIAxes, 'off')
            end
        end

        % yinput value changed function
        function yinputValueChanged(app)
            app.valuey = app.yinput.Value;          
        end

        % points value changed function
        function pointsValueChanged(app)
            value = app.points.Value;
            if value == 1
                plot(app.UIAxes, eval(app.valuey), '-*')
            else
                plot(app.UIAxes, eval(app.valuey), '-')
            end
        end

        % x1 value changed function
        function x1ValueChanged(app)
            app.valuex1 = app.x1.Value;
            
        end

        % x2 value changed function
        function x2ValueChanged(app)
            app.valuex2 = app.x2.Value;
            
        end

        % n value changed function
        function nValueChanged(app)
            app.valuen = app.n.Value;
            
        end

        % plotGRAPH button pushed function
        function plotGRAPHButtonPushed(app)
            app.x11 = eval(app.valuex1);
            app.x22 = eval(app.valuex2); 
            app.nn = app.valuen;
            app.x = linspace(app.x11, app.x22, app.nn);
            app.y = eval(app.valuey);
            plot(app.UIAxes, app.x, app.y);


        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            setAutoResize(app, app.UIFigure, true)
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title');
            xlabel(app.UIAxes, 'X');
            ylabel(app.UIAxes, 'Y');
            app.plotGRAPH = uibutton(app.UIFigure, 'push');
            app.plotGRAPH.ButtonPushedFcn = createCallbackFcn(app, @plotGRAPHButtonPushed);
            app.plotGRAPH.Text = 'построить график ';
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'y';
            app.yinput = uieditfield(app.UIFigure, 'text');
            app.yinput.ValueChangedFcn = createCallbackFcn(app, @yinputValueChanged);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'ПОСТРОЕНИЕ ГРАФИКОВ';
            app.grid = uicheckbox(app.UIFigure);
            app.grid.ValueChangedFcn = createCallbackFcn(app, @gridValueChanged);
            app.grid.Text = 'грид';
            app.points = uicheckbox(app.UIFigure);
            app.points.ValueChangedFcn = createCallbackFcn(app, @pointsValueChanged);
            app.points.Text = 'точки';
            app.LabelEditField2 = uilabel(app.UIFigure);
            app.LabelEditField2.Text = 'x1';
            app.x1 = uieditfield(app.UIFigure, 'text');
            app.x1.ValueChangedFcn = createCallbackFcn(app, @x1ValueChanged);
            app.LabelNumericEditField = uilabel(app.UIFigure);
            app.LabelNumericEditField.Text = 'n';
            app.n = uieditfield(app.UIFigure, 'numeric');
            app.n.ValueChangedFcn = createCallbackFcn(app, @nValueChanged);
            app.LabelEditField3 = uilabel(app.UIFigure);
            app.LabelEditField3.Text = 'x2';
            app.x2 = uieditfield(app.UIFigure, 'text');
            app.x2.ValueChangedFcn = createCallbackFcn(app, @x2ValueChanged);
        end
    end

    methods (Access = public)

        % Construct app
        function app = praktikagraph()

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end